classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        recorder              matlab.ui.control.StateButton
        Label_3               matlab.ui.control.Label
        Gauge                 matlab.ui.control.NinetyDegreeGauge
        IPLabel               matlab.ui.control.Label
        IPEditField           matlab.ui.control.EditField
        EditFieldLabel        matlab.ui.control.Label
        portEditField         matlab.ui.control.NumericEditField
        dAEditFieldLabel      matlab.ui.control.Label
        dAEditField           matlab.ui.control.NumericEditField
        dBEditFieldLabel      matlab.ui.control.Label
        dBEditField           matlab.ui.control.NumericEditField
        mLabel                matlab.ui.control.Label
        mLabel_2              matlab.ui.control.Label
        beepbeepAbtn          matlab.ui.control.StateButton
        beepbeepBbtn          matlab.ui.control.StateButton
        mode1Lamp             matlab.ui.control.Lamp
        mode2Lamp             matlab.ui.control.Lamp
        mode3Lamp             matlab.ui.control.Lamp
        tcpLampLabel          matlab.ui.control.Label
        tcpLamp               matlab.ui.control.Lamp
        Label_4               matlab.ui.control.Label
        distEditField         matlab.ui.control.NumericEditField
        mLabel_3              matlab.ui.control.Label
        f0EditFieldLabel      matlab.ui.control.Label
        chirpf0EditField      matlab.ui.control.NumericEditField
        f1EditFieldLabel      matlab.ui.control.Label
        chirpf1EditField      matlab.ui.control.NumericEditField
        TEditFieldLabel       matlab.ui.control.Label
        chirpTEditField       matlab.ui.control.NumericEditField
        methodEditFieldLabel  matlab.ui.control.Label
        methodEditField       matlab.ui.control.EditField
        RightPanel            matlab.ui.container.Panel
        Label_2               matlab.ui.control.Label
        sendInfo              matlab.ui.control.TextArea
        sendBtn               matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        recvInfo              matlab.ui.control.TextArea
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        myRecorder  audiorecorder; % 录音
        t timer; % 录音计时器
        beepbeep_mode = 0; % beepbeep的传输内容
        beepbeep_type = 'A'; % beepbeep是设备A或B
        preamble_bit_num = 4; % 前导码长度
        preamble_code = [0 1 0 1]; % 前导码
        packetLenLen = 8; % 包长度码的长度
        mode = 0; % FSK接受的阶段
        t1 = 0;
        t3 = 0;
        
        chirp_signal = chirp(0:1/48000:0.1, 6000, 0.1, 8000);
        chirp_f0 = 6000;
        chirp_f1 = 8000;
        chirp_T = 0.1;
        chirp_method;
        
        
        pStart = 0; % 当前包的开头点
        
        f0 = 4000; % 低频率
        f1 = 6000; % 高频率
        duration = 0.025; % 间隔
        Fs = 48000; % 采样率
        
        data_recv = -1; % tcp server 收到的信息
        ip; % tcp传输的ip地址
        port; % tcp传输的目标端口
        
        dA = 0; % 设备A的麦克风与扬声器的距离
        dB = 0; % 设备B的麦克风与扬声器的距离
        
        t_server;
        t_client;
    end
    
    properties (Access = private)
        
    end
    
    methods (Access = private)
        
        function recorderTimerFcn(app)
            myRecording = getaudiodata(app.myRecorder);
            plot(app.UIAxes, myRecording(end-100:end));
        end
    end
    
    methods (Access = public)
        
        function demod_packet(app)
            myRecording = getaudiodata(app.myRecorder);
            app.pStart = 0;
            str = decode (myRecording,app.preamble_code);
            app.recvInfo.Value = str;
            str
            return
            
            while(true)
                app.pStart = position(myRecording(app.pStart:end), app.preamble_code, app.f0, app.f1, app.duration);
                if app.pStart == -1
                    break;
                end
                app.mode2Lamp.Color = [0 1 0];
                
                if length(myRecording) - app.pStart > app.duration * app.Fs * (app.preamble_bit_num + app.packetLenLen)
                    app.pLen = decode_header(myRecording(app.pStart, end), app.Fs, app.duration, app.f0, app.f1, app.preamble_bit_num);
                    app.mode3Lamp.Color = [0 1 0];
                end
                
                if length(myRecording) - app.pStart > (app.pLen + app.preamble_bit_num + app.packetLenLen) * app.duration * app.Fs
                    code = my_FSK_demod(myRecording(app.pStart + app.Fs * app.duration * (app.preamble_bit_num + app.packetLenLen), ...
                        app.pStart + (app.pLen + app.preamble_bit_num + app.packetLenLen) * app.Fs * app.duration), app.Fs, app.duration, app.f0, app.f1 );
                    app.outStr = [app.outStr int2str(code)];
                    app.recvInfo.Value = app.outStr;
                end
                
                app.mode2Lamp.Color = [1 0 0];
                app.mode3Lamp.Color = [1 0 0];
            end
        end
        
        function demod_beepbeep(app)
            myRecording = getaudiodata(app.myRecorder);
            audiowrite('beepbeep.wav', myRecording, app.Fs);
            
            app.t1 = position(myRecording, app.chirp_signal, length(app.chirp_signal), app.chirp_f0, app.chirp_f1);            
            if app.t1 == -1
                'beepbeep t1 解析出错'
                return
            end
            app.t3 = position(myRecording(app.t1 + length(app.chirp_signal): end), app.chirp_signal, length(app.chirp_signal), app.chirp_f0, app.chirp_f1);
            if app.t3 == -1
                'beepbeep t3 解析出错'
                return
            end
            app.t3 = app.t3 + app.t1 + length(app.chirp_signal);
            %plot_corr(myRecording,app.chirp_signal, length(app.chirp_signal),app.t1,app.t3,app.chirp_f0, app.chirp_f1);
        end
        
        function get_chirp_args(app)
            app.chirp_f0 = app.chirpf0EditField.Value;
            app.chirp_f1 = app.chirpf1EditField.Value;
            app.chirp_T = app.chirpTEditField.Value;
            app.chirp_method = app.methodEditField.Value;
            app.chirp_signal = chirp(0:1/app.Fs:app.chirp_T, app.chirp_f0, app.chirp_T, app.chirp_f1, app.chirp_method);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: recorder
        function recorderValueChanged(app, event)
            
            value = app.recorder.Value;
            if  value == 1
                app.t= timer('Period',0.1,'ExecutionMode',"fixedRate");
                app.myRecorder = audiorecorder(app.Fs, 24, 1);
                record(app.myRecorder);
                app.t.StartDelay = 0.6;
                app.t.Period = 0.1;
                app.t.TimerFcn=@(~,~)recorderTF(app);
                start(app.t);
                app.mode = 0;
                app.mode1Lamp.Color = [0 1 0];
                
                app.recvInfo.Value = '';
                
            elseif  value == 0
                stop(app.t);
                delete(app.t);
                pause(app.myRecorder);
                %play(app.myRecorder);
                audiowrite('test.wav',getaudiodata(app.myRecorder),app.Fs);
                demod_packet(app);
                
            end
        end

        % Value changed function: beepbeepAbtn
        function beepbeepAbtnValueChanged(app, event)
            value = app.beepbeepAbtn.Value;
            
            if value == 1
                get_chirp_args(app);
                app.ip = app.IPEditField.Value;
                app.port = app.portEditField.Value;
                app.dA = app.dAEditField.Value;
                app.dB = app.dBEditField.Value;
                
                app.distEditField.Value = 0;
                
                app.t_server = tcpip('0.0.0.0', app.port, 'NetworkRole', 'server');
                fopen(app.t_server);
                app.tcpLamp.Color = [0 1 0];
                
                app.myRecorder = audiorecorder(app.Fs,24,1);
                record(app.myRecorder);
                app.mode1Lamp.Color = [0 1 0];
                
                pause(0.2);
                sound(app.chirp_signal, app.Fs);
                pause(0.5 + app.chirp_T);
                
                fwrite(app.t_server, '-1', 'int');
                
                tB_sub = 0;
                while(true)
                    if app.t_server.BytesAvailable > 0
                        tB_sub = fread(app.t_server, app.t_server.BytesAvailable / 4, 'int');
                        break;
                    end
                end
                
                pause(app.myRecorder);
                app.demod_beepbeep();
                app
                tB_sub
                dist = 0.5 * 340 * ((app.t3 - app.t1) - tB_sub) / app.Fs + app.dA + app.dB
                app.distEditField.Value = dist;
                
                
                app.mode1Lamp.Color = [1 0 0];
                app.mode2Lamp.Color = [1 0 0];
                app.mode3Lamp.Color = [1 0 0];
                app.tcpLamp.Color = [1 0 0];
                fclose(app.t_server);
                app.beepbeepAbtn.Value = 0;
                
                
            elseif value == 0
                pause(app.myRecorder);
                fclose(app.t_server);
                app.mode1Lamp.Color = [1 0 0];
                app.mode2Lamp.Color = [1 0 0];
                app.mode3Lamp.Color = [1 0 0];
                app.tcpLamp.Color = [1 0 0];
            end
        end

        % Value changed function: beepbeepBbtn
        function beepbeepBbtnValueChanged(app, event)
            value = app.beepbeepBbtn.Value;
            if value == 1
                get_chirp_args(app);
                app.mode1Lamp.Color = [0 1 0];
                app.ip = app.IPEditField.Value;
                app.port = app.portEditField.Value;
                
                app.distEditField.Value = 0;
                
                app.myRecorder = audiorecorder(app.Fs,24,1);
                record(app.myRecorder);
                app.mode1Lamp.Color = [0 1 0];
                pause(0.5);
                
                app.t_client = tcpip(app.ip, app.port, 'NetworkRole', 'client');
                fopen(app.t_client);
                app.tcpLamp.Color = [0 1 0];


                while(true)
                    if app.t_client.BytesAvailable > 0
                        fread(app.t_client, app.t_client.BytesAvailable / 4, 'int');
                        break;
                    end
                end
                app.mode2Lamp.Color = [0 1 0];
                
                sound(app.chirp_signal, app.Fs);
                pause(0.5 + app.chirp_T);
                
                app.mode3Lamp.Color = [0 1 0];
                pause(app.myRecorder);
                app.demod_beepbeep();
                app
                fwrite(app.t_client, app.t3-app.t1, "int");
                
                fclose(app.t_client);
                
                app.mode1Lamp.Color = [1 0 0];
                app.mode2Lamp.Color = [1 0 0];
                app.mode3Lamp.Color = [1 0 0];
                app.tcpLamp.Color = [1 0 0];

                
            elseif value == 0
                pause(app.myRecorder);
                fclose(app.t_client);
                app.mode1Lamp.Color = [1 0 0];
                app.mode2Lamp.Color = [1 0 0];
                app.mode3Lamp.Color = [1 0 0];
                app.tcpLamp.Color = [1 0 0];
            end
        end

        % Button pushed function: sendBtn
        function sendBtnButtonPushed(app, event)
            %app.sendInfo.Value
            signal = encode(char(app.sendInfo.Value), app.preamble_code);
            sound(signal, app.Fs);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {522, 522};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {248, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.recorder = uibutton(app.LeftPanel, 'state');
            app.recorder.ValueChangedFcn = createCallbackFcn(app, @recorderValueChanged, true);
            app.recorder.Text = '接受信号';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '信号强度';
            app.Gauge = uigauge(app.LeftPanel, 'ninetydegree');
            app.IPLabel = uilabel(app.LeftPanel);
            app.IPLabel.Text = 'IP地址';
            app.IPEditField = uieditfield(app.LeftPanel, 'text');
            app.IPEditField.Value = '172.20.10.9';
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = '端口';
            app.portEditField = uieditfield(app.LeftPanel, 'numeric');
            app.portEditField.Value = 30000;
            app.dAEditFieldLabel = uilabel(app.LeftPanel);
            app.dAEditFieldLabel.Text = 'dA';
            app.dAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dBEditFieldLabel = uilabel(app.LeftPanel);
            app.dBEditFieldLabel.Text = 'dB';
            app.dBEditField = uieditfield(app.LeftPanel, 'numeric');
            app.mLabel = uilabel(app.LeftPanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.LeftPanel);
            app.mLabel_2.Text = 'm';
            app.beepbeepAbtn = uibutton(app.LeftPanel, 'state');
            app.beepbeepAbtn.ValueChangedFcn = createCallbackFcn(app, @beepbeepAbtnValueChanged, true);
            app.beepbeepAbtn.Text = '测距A';
            app.beepbeepBbtn = uibutton(app.LeftPanel, 'state');
            app.beepbeepBbtn.ValueChangedFcn = createCallbackFcn(app, @beepbeepBbtnValueChanged, true);
            app.beepbeepBbtn.Text = '测距B';
            app.mode1Lamp = uilamp(app.LeftPanel);
            app.mode2Lamp = uilamp(app.LeftPanel);
            app.mode3Lamp = uilamp(app.LeftPanel);
            app.tcpLampLabel = uilabel(app.LeftPanel);
            app.tcpLampLabel.Text = '建立起tcp连接';
            app.tcpLamp = uilamp(app.LeftPanel);
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '距离为';
            app.distEditField = uieditfield(app.LeftPanel, 'numeric');
            app.mLabel_3 = uilabel(app.LeftPanel);
            app.mLabel_3.Text = 'm';
            app.f0EditFieldLabel = uilabel(app.LeftPanel);
            app.f0EditFieldLabel.Text = 'f0';
            app.chirpf0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.chirpf0EditField.Value = 3000;
            app.f1EditFieldLabel = uilabel(app.LeftPanel);
            app.f1EditFieldLabel.Text = 'f1';
            app.chirpf1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.chirpf1EditField.Value = 6000;
            app.TEditFieldLabel = uilabel(app.LeftPanel);
            app.TEditFieldLabel.Text = 'T';
            app.chirpTEditField = uieditfield(app.LeftPanel, 'numeric');
            app.chirpTEditField.Value = 0.1;
            app.methodEditFieldLabel = uilabel(app.LeftPanel);
            app.methodEditFieldLabel.Text = 'method';
            app.methodEditField = uieditfield(app.LeftPanel, 'text');
            app.methodEditField.Value = 'linear';
            app.RightPanel = uipanel(app.GridLayout);
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '发送信号';
            app.sendInfo = uitextarea(app.RightPanel);
            app.sendBtn = uibutton(app.RightPanel, 'push');
            app.sendBtn.ButtonPushedFcn = createCallbackFcn(app, @sendBtnButtonPushed, true);
            app.sendBtn.Text = '发送';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '接收信号';
            app.recvInfo = uitextarea(app.RightPanel);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end